classdef Routh < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RouthUI                   matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        RouthLabel                matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        ShowRoots                 matlab.ui.control.Button
        Nroots                    matlab.ui.control.TextArea
        calcular                  matlab.ui.control.Button
        Var                       matlab.ui.control.EditField
        HallarintervaloparaLabel  matlab.ui.control.Label
        Panel_2                   matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        output                    matlab.ui.control.Label
        IngreseecuacinLabel       matlab.ui.control.Label
        input                     matlab.ui.control.EditField
    end

    
    properties (Access = private)
        DialogApp
    end
    
    properties (Access = public)
        pol
    end
    
    methods (Access = private)
        
        function results = auxPol(~,exp,matrixRouth)
            % calcula el polinomio auxiliar.
            for j = 1:length(matrixRouth)
                matrixRouth(1,j) = (exp-2*(j-1))*matrixRouth(1,j);
            end
            results = matrixRouth;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcular
        function calcularPushed(app, event)
            syms s k
            
            if (~strcmp('', app.input.Value))
                
                try
                    %=================== determina si se está usando k u otra variable ========%
                    [~, var] = coeffs(str2sym(app.input.Value), k, 'All');
                    useK = length(var) ~= 1;
                    
                    if useK
                        app.ShowRoots.Enable = 'off';
                        app.ShowRoots.Tooltip = ['No se pueden calcular las ' ...
                            'raices con una variable k'];
                    else
                        
                        try
                            sym2poly(str2sym(app.input.Value));
                            app.ShowRoots.Enable = 'on';
                            app.ShowRoots.Tooltip = '';
                        catch
                            useK = true;
                            app.ShowRoots.Enable = 'off';
                            app.ShowRoots.Tooltip = ['No se pueden calcular ' ...
                                'las raices con una variable diferente de s y k'];
                        end
                        
                    end
                    
                    %================== calcula la matriz de routh =============================%
                    [app.pol, var] = coeffs(str2sym(app.input.Value), s, 'All');
                    
                    m = length(app.pol); n = ceil(m / 2);
                    matrixRouth = sym(zeros(m, n)); EPS = zeros(m, 1);
                    
                    for i = 1:m
                        cero = true;
                        
                        for j = 1:n
                            
                            if m - i -2 * (j - 1) >= 0
                                
                                if i < 3 && 2 * j + i - 2 <= m
                                    matrixRouth(i, j) = app.pol(2 * j + i - 2);
                                elseif j + 1 <= n
                                    matrixRouth(i, j) = ...
                                        (matrixRouth(i - 1, 1) * matrixRouth(i - 2, j + 1) ...
                                        -matrixRouth(i - 2, 1) * matrixRouth(i - 1, j + 1)) ...
                                        / matrixRouth(i - 1, 1);
                                end
                                
                                if matrixRouth(i, j) ~= 0
                                    cero = false;
                                end
                                
                            end
                            
                        end
                        
                        if cero && i < m - 1
                            matrixRouth(i, :) = auxPol(app, m - i + 1, matrixRouth(i - 1, :));
                        elseif matrixRouth(i, 1) == 0
                            matrixRouth(i, 1) = 'eps';
                            EPS(i) = 1;
                        end
                        
                    end
                    
                    %==================== escribe el resultado==================================
                    string = '';
                    
                    for i = 1:m
                        string = sprintf("%s%s\t│\t", string, char(var(i)));
                        
                        for j = 1:n
                            
                            if m - i -2 * (j - 1) >= 0
                                string = sprintf("%s%s\t\t", string, char(matrixRouth(i, j)));
                            end
                            
                        end
                        
                        string = sprintf('%s\n\t│\n', string);
                    end
                    
                    app.output.Text = string;
                    matrixRouth = subs(matrixRouth, {sym('eps')}, {eps});
                    
                    %==================== determina si el sistema es estable.===================
                    if useK
                        dominio = feval(symengine, 'solve', matrixRouth(:, 1) >= 0, app.Var.Value , 'Real');
                        
                        if isempty(dominio)
                            app.Nroots.Value = sprintf("el sistema es inestable para todo valor de '%s'", app.Var.Value);
                        else
                            app.Nroots.Value = sprintf("el sistema es estable para '%s' en el %s", app.Var.Value, evalc('dominio'));
                        end
                        
                    else
                        signo = sign(matrixRouth(1, 1));
                        cont = 0;
                        
                        for i = 2:m
                            
                            if signo ~= sign(matrixRouth(i, 1)) && EPS(i) ~= 1
                                cont = cont + 1;
                                signo = sign(matrixRouth(i, 1));
                            end
                            
                        end
                        
                        app.Nroots.Value = sprintf("El sistema tiene %i raices inestables", cont);
                    end
                    
                catch
                    errordlg('expression invalida', 'error')
                end
                
            else
                errordlg('Por favor ingrese una exprecion', 'Error')
            end
            
        end

        % Button pushed function: ShowRoots
        function ShowRootsCall(app, event)
            app.ShowRoots.Enable = 'off';
            app.calcular.Enable = 'off';
            app.calcular.Tooltip = 'Debe cerrar la ventana Raices para hacer más calculos';
            app.DialogApp = showroots(app);
        end

        % Close request function: RouthUI
        function RouthUICloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RouthUI = uifigure('Visible', 'off');
            app.RouthUI.Name = 'Routh';
            app.RouthUI.CloseRequestFcn = createCallbackFcn(app, @RouthUICloseRequest, true);
            app.GridLayout = uigridlayout(app.RouthUI);
            app.RouthLabel = uilabel(app.GridLayout);
            app.RouthLabel.Text = 'Routh';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ShowRoots = uibutton(app.GridLayout2, 'push');
            app.ShowRoots.ButtonPushedFcn = createCallbackFcn(app, @ShowRootsCall, true);
            app.ShowRoots.Enable = 'off';
            app.ShowRoots.Text = 'Mostrar raices';
            app.Nroots = uitextarea(app.GridLayout2);
            app.calcular = uibutton(app.GridLayout2, 'push');
            app.calcular.ButtonPushedFcn = createCallbackFcn(app, @calcularPushed, true);
            app.calcular.Text = 'Calcular';
            app.Var = uieditfield(app.GridLayout2, 'text');
            app.Var.Value = 'k';
            app.HallarintervaloparaLabel = uilabel(app.GridLayout2);
            app.HallarintervaloparaLabel.Text = 'Hallar intervalo para:';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.output = uilabel(app.GridLayout3);
            app.output.Text = '';
            app.IngreseecuacinLabel = uilabel(app.GridLayout);
            app.IngreseecuacinLabel.Text = 'Ingrese ecuación';
            app.input = uieditfield(app.GridLayout, 'text');
            app.input.Tooltip = {'Ingrese la ecuacion en su forma lineal.'; 'ej: (1+k)*s^3+4*s^2+2*s+5'};
            % Show the figure after all components are created
            app.RouthUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Routh

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RouthUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RouthUI)
        end
    end
end