classdef showroots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Roots        matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        RaicesLabel  matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
        Panel        matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        Label        matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
            raices = roots(app.callingApp.pol);
            plot(app.UIAxes,real(raices),imag(raices),'x')
            String = sprintf('Las raices son: \n\n');
            n = length(raices);
            Rn = sym('R%d', [1 n]);
            for i = 1:n
                if sign(imag(raices(i))) == -1
                    sig = '';
                    
                else
                    sig = '+';
                end
                String = sprintf('%s%s = %f %s %fi\n', String, Rn(i), real(raices(i)), sig, imag(raices(i)));
            end
            app.Label.Text = String;
        end

        % Close request function: Roots
        function RootsCloseRequest(app, event)
            app.callingApp.ShowRoots.Enable = 'on';
            app.callingApp.calcular.Enable = 'on';
            app.callingApp.calcular.Tooltip = '';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Roots = uifigure('Visible', 'off');
            app.Roots.Name = 'Raices';
            app.Roots.CloseRequestFcn = createCallbackFcn(app, @RootsCloseRequest, true);
            app.GridLayout = uigridlayout(app.Roots);
            app.RaicesLabel = uilabel(app.GridLayout);
            app.RaicesLabel.Text = 'Raices';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Raiz')
            xlabel(app.UIAxes, 'Real')
            ylabel(app.UIAxes, 'Img')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.Roots.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = showroots(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Roots)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Roots)
        end
    end
end